classdef App_Dynamic_loc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectKIMTrajFolderButton     matlab.ui.control.Button
        SelectRobotTrajFileButton     matlab.ui.control.Button
        SelectcoordinateFileButton    matlab.ui.control.Button
        AnalyseButton                 matlab.ui.control.Button
        SelecttheparameterfileButton  matlab.ui.control.Button
        CoordinatesofthemarkerswithrespecttotheisocentreLabel  matlab.ui.control.Label
        ParametertomatchKIMandrobottracesintimeLabel  matlab.ui.control.Label
        FormatxyzinEclipseElektaconventionLabel  matlab.ui.control.Label
        FormattTxTyTzrxryrzLabel      matlab.ui.control.Label
        KIMgeneratedtrajectoryfileLabel  matlab.ui.control.Label
        RobottracesLabel              matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: SelectKIMTrajFolderButton
        function SelectKIMTrajFolderButtonPushed(app, event)
            global KIM
            folder_name = uigetdir('C:\');     
            KIM.KIMTrajFolder=folder_name;
     
        end

        % Button pushed function: SelectRobotTrajFileButton
        function SelectRobotTrajFileButtonPushed(app, event)
          global KIM
          [FileName,PathName,~] = uigetfile('C:\');  
          KIM.KIMRobotFile=[PathName FileName];
        end

        % Callback function
        function SelectCoordinateFileButtonPushed(app, event)
      
        end

        % Callback function
        function AnalyseButtonPushed(app, event)
            global KIM
            %KIM.handles=handles;
            
        end

        % Button pushed function: SelectcoordinateFileButton
        function SelectcoordinateFileButtonPushed(app, event)
        global KIM
        [FileName,PathName,~] = uigetfile('C:\');
        KIM.KIMcoordFile=[PathName FileName]
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed2(app, event)
            global KIM
            %KIM.handles=handles;
            Dynamicloc(KIM.KIMRobotFile, KIM.KIMTrajFolder, KIM.KIMcoordFile, KIM.KIMparamFile)
        end

        % Button pushed function: SelecttheparameterfileButton
        function SelecttheparameterfileButtonPushed(app, event)
        global KIM
        [FileName,PathName,~] = uigetfile('C:\');
        KIM.KIMparamFile=[PathName FileName]    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectKIMTrajFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectKIMTrajFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKIMTrajFolderButtonPushed, true);
            app.SelectKIMTrajFolderButton.Text = 'Select KIM Traj Folder';
            app.SelectRobotTrajFileButton = uibutton(app.UIFigure, 'push');
            app.SelectRobotTrajFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRobotTrajFileButtonPushed, true);
            app.SelectRobotTrajFileButton.Text = 'Select Robot Traj File';
            app.SelectcoordinateFileButton = uibutton(app.UIFigure, 'push');
            app.SelectcoordinateFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcoordinateFileButtonPushed, true);
            app.SelectcoordinateFileButton.Text = 'Select coordinate File';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed2, true);
            app.AnalyseButton.Text = 'Analyse';
            app.SelecttheparameterfileButton = uibutton(app.UIFigure, 'push');
            app.SelecttheparameterfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheparameterfileButtonPushed, true);
            app.SelecttheparameterfileButton.Text = 'Select the parameter file';
            app.CoordinatesofthemarkerswithrespecttotheisocentreLabel = uilabel(app.UIFigure);
            app.CoordinatesofthemarkerswithrespecttotheisocentreLabel.Text = '(Coordinates of the markers with respect to the isocentre)';
            app.ParametertomatchKIMandrobottracesintimeLabel = uilabel(app.UIFigure);
            app.ParametertomatchKIMandrobottracesintimeLabel.Text = '(Parameter to match KIM and robot traces in time)';
            app.FormatxyzinEclipseElektaconventionLabel = uilabel(app.UIFigure);
            app.FormatxyzinEclipseElektaconventionLabel.Text = 'Format: x, y, z in Eclipse/Elekta convention';
            app.FormattTxTyTzrxryrzLabel = uilabel(app.UIFigure);
            app.FormattTxTyTzrxryrzLabel.Text = 'Format: t, Tx, Ty, Tz, rx, ry, rz';
            app.KIMgeneratedtrajectoryfileLabel = uilabel(app.UIFigure);
            app.KIMgeneratedtrajectoryfileLabel.Text = '(KIM generated trajectory file)';
            app.RobottracesLabel = uilabel(app.UIFigure);
            app.RobottracesLabel.Text = '(Robot traces), ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App_Dynamic_loc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end