classdef staticTest_LARK_executable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ParentpathButton          matlab.ui.control.Button
        CoordinatefileButton      matlab.ui.control.Button
        LONGSIEditFieldLabel      matlab.ui.control.Label
        LONGSIEditField           matlab.ui.control.EditField
        VRTAPEditFieldLabel       matlab.ui.control.Label
        VRTAPEditField            matlab.ui.control.EditField
        ComputeKIMaccuracyButton  matlab.ui.control.Button
        StaticshiftsmmLabel       matlab.ui.control.Label
        LATLREditFieldLabel       matlab.ui.control.Label
        LATLREditField            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: ParentpathButton
        function ParentpathButtonPushed(app, event)
            global KIM;
            ParentPath = uigetdir('C:\'); 
            KIM.ParentPath = ParentPath;
             
        end

        % Button pushed function: CoordinatefileButton
        function CoordinatefileButtonPushed(app, event)
            global KIM;
            [FileName,PathName,~] = uigetfile('C:\'); 
            KIM.coord = [PathName FileName];

        end

        % Callback function
        function LATLREditFieldValueChanged(app, event)
           global KIM;
            value_LR = app.LONGLREditField.Value;
            KIM.value_LR = value_LR;
            
        end

        % Value changing function: LONGSIEditField
        function LONGSIEditFieldValueChanging(app, event)
            
            
        end

        % Value changed function: LONGSIEditField
        function LONGSIEditFieldValueChanged(app, event)
            global KIM;
            value_SI = app.LONGSIEditField.Value;
            KIM.value_SI = value_SI;

        end

        % Value changed function: VRTAPEditField
        function VRTAPEditFieldValueChanged(app, event)
            global KIM;
            value_AP = app.VRTAPEditField.Value;
            KIM.value_AP = value_AP;

        end

        % Button pushed function: ComputeKIMaccuracyButton
        function ComputeKIMaccuracyButtonPushed(app, event)
             global KIM;
             coord = KIM.coord;
             value_SI = KIM.value_SI;
             value_LR = KIM.value_LR;
             value_AP = KIM.value_AP;
             
             ParentPath = KIM.ParentPath;
            
             
             Staticloc(value_LR, value_SI, value_AP, ParentPath, coord)
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            
        end

        % Callback function
        function LATLREditFieldValueChanged2(app, event)
            value = app.LATLREditField.Value;
            
        end

        % Value changed function: LATLREditField
        function LATLREditFieldValueChanged3(app, event)
            value = app.LATLREditField.Value;
            global KIM;
            value_LR = app.LATLREditField.Value;
            KIM.value_LR = value_LR;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ParentpathButton = uibutton(app.UIFigure, 'push');
            app.ParentpathButton.ButtonPushedFcn = createCallbackFcn(app, @ParentpathButtonPushed, true);
            app.ParentpathButton.Text = 'Parent path';
            app.CoordinatefileButton = uibutton(app.UIFigure, 'push');
            app.CoordinatefileButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinatefileButtonPushed, true);
            app.CoordinatefileButton.Text = 'Coordinate file';
            app.LONGSIEditFieldLabel = uilabel(app.UIFigure);
            app.LONGSIEditFieldLabel.Text = 'LONG (SI)';
            app.LONGSIEditField = uieditfield(app.UIFigure, 'text');
            app.LONGSIEditField.ValueChangedFcn = createCallbackFcn(app, @LONGSIEditFieldValueChanged, true);
            app.LONGSIEditField.ValueChangingFcn = createCallbackFcn(app, @LONGSIEditFieldValueChanging, true);
            app.VRTAPEditFieldLabel = uilabel(app.UIFigure);
            app.VRTAPEditFieldLabel.Text = 'VRT (-AP)';
            app.VRTAPEditField = uieditfield(app.UIFigure, 'text');
            app.VRTAPEditField.ValueChangedFcn = createCallbackFcn(app, @VRTAPEditFieldValueChanged, true);
            app.ComputeKIMaccuracyButton = uibutton(app.UIFigure, 'push');
            app.ComputeKIMaccuracyButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeKIMaccuracyButtonPushed, true);
            app.ComputeKIMaccuracyButton.Text = 'Compute KIM accuracy ';
            app.StaticshiftsmmLabel = uilabel(app.UIFigure);
            app.StaticshiftsmmLabel.Text = 'Static shifts (mm):';
            app.LATLREditFieldLabel = uilabel(app.UIFigure);
            app.LATLREditFieldLabel.Text = 'LAT (LR)';
            app.LATLREditField = uieditfield(app.UIFigure, 'text');
            app.LATLREditField.ValueChangedFcn = createCallbackFcn(app, @LATLREditFieldValueChanged3, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = staticTest_LARK_executable

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end